classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HeartDiseaseLabel               matlab.ui.control.Label
        FeatureSelectionReductionPanel  matlab.ui.container.Panel
        SelectionPanel                  matlab.ui.container.Panel
        KruskalWallisCheckBox           matlab.ui.control.CheckBox
        ReductionPanel                  matlab.ui.container.Panel
        ModelDropDown_3Label            matlab.ui.control.Label
        ModelDropDown_3                 matlab.ui.control.DropDown
        NFeaturesEditFieldLabel         matlab.ui.control.Label
        NFeaturesEditField              matlab.ui.control.NumericEditField
        PlotButton                      matlab.ui.control.Button
        ClassificationPanel             matlab.ui.container.Panel
        ClassifyButton                  matlab.ui.control.Button
        ModelDropDownLabel              matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        CostEditField_2Label            matlab.ui.control.Label
        CostEditField_2                 matlab.ui.control.NumericEditField
        GammaEditField_2Label           matlab.ui.control.Label
        GammaEditField_2                matlab.ui.control.NumericEditField
        NeighborsEditField_2Label       matlab.ui.control.Label
        NeighborsEditField_2            matlab.ui.control.NumericEditField
        ScenariosPanel                  matlab.ui.container.Panel
        SelectDropDownLabel             matlab.ui.control.Label
        SelectDropDown                  matlab.ui.control.DropDown
        FeatureAssessmentPanel          matlab.ui.container.Panel
        AssessButton                    matlab.ui.control.Button
        AssessButton_2                  matlab.ui.control.Button
        PreprocessingPanel              matlab.ui.container.Panel
        ScaleCheckBox                   matlab.ui.control.CheckBox
        HyperparametersTunningPanel     matlab.ui.container.Panel
        SearchButton                    matlab.ui.control.Button
        ModelDropDown_2Label            matlab.ui.control.Label
        ModelDropDown_2                 matlab.ui.control.DropDown
        NeighborsEditFieldLabel         matlab.ui.control.Label
        NeighborsEditField              matlab.ui.control.NumericEditField
        CostEditFieldLabel              matlab.ui.control.Label
        CostEditField                   matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        GammaEditField                  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModelDropDown_2
        function ModelDropDown_2ValueChanged(app, event)
            value = app.ModelDropDown_2.Value;
            if strcmp(value,app.ModelDropDown_2.ItemsData(1))
                app.CostEditField.Visible = false;
                app.CostEditFieldLabel.Visible = false;
                app.GammaEditField.Visible = false;
                app.GammaEditFieldLabel.Visible = false;
                app.NeighborsEditField.Visible = true;
                app.NeighborsEditFieldLabel.Visible = true;
            else
                app.NeighborsEditField.Visible = false;
                app.NeighborsEditFieldLabel.Visible = false;
                app.CostEditField.Visible = true;
                app.CostEditFieldLabel.Visible = true;
                app.GammaEditField.Visible = true;
                app.GammaEditFieldLabel.Visible = true;

            end
        end

        % Value changed function: KruskalWallisCheckBox
        function KruskalWallisCheckBoxValueChanged(app, event)
            value = app.KruskalWallisCheckBox.Value;
            if value == true
                app.ModelDropDown_3.Value = "None";
                app.ModelDropDown_3.Enable = false;
            else
                app.ModelDropDown_3.Enable = true;
                app.ModelDropDown_3.Value = app.ModelDropDown_3.Items(1);
            end
        end

        % Value changed function: NeighborsEditField_2
        function NeighborsEditField_2ValueChanged(app, event)
            value = app.NeighborsEditField_2.Value;
            
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            
            if strcmp(value, "K - Nearest Neighbors")
                app.NeighborsEditField_2Label.Visible = true;
                app.NeighborsEditField_2.Visible = true;
            else
                app.NeighborsEditField_2Label.Visible = false;
                app.NeighborsEditField_2.Visible = false;
            end
            
            if strcmp(value, "Support Vector Machine")
                app.CostEditField_2Label.Visible = true;
                app.CostEditField_2.Visible = true;
                app.GammaEditField_2Label.Visible = true;
                app.GammaEditField_2.Visible = true;
            else
                app.CostEditField_2Label.Visible = false;
                app.CostEditField_2.Visible = false;
                app.GammaEditField_2Label.Visible = false;
                app.GammaEditField_2.Visible = false;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            scenario = app.SelectDropDown.Value;
            scale = app.ScaleCheckBox.Value;
            assess = false;
            if app.KruskalWallisCheckBox.Value
                technique = "KW";
            else
                technique = app.ModelDropDown_3.Value;
            end
            n_features = app.NFeaturesEditField.Value;
            app_main(scenario, scale, assess, technique, n_features);
        end

        % Button pushed function: AssessButton_2
        function AssessButton_2Pushed(app, event)
            scenario = app.SelectDropDown.Value;
            scale = app.ScaleCheckBox.Value;
            assess = true;
            technique = "None";
            n_features = 0;
            app_main(scenario, scale, assess, technique, n_features);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HeartDiseaseLabel = uilabel(app.UIFigure);
            app.HeartDiseaseLabel.Text = 'Heart Disease';
            app.FeatureSelectionReductionPanel = uipanel(app.UIFigure);
            app.FeatureSelectionReductionPanel.Title = '4 - Feature Selection / Reduction';
            app.SelectionPanel = uipanel(app.FeatureSelectionReductionPanel);
            app.SelectionPanel.Title = 'Selection';
            app.KruskalWallisCheckBox = uicheckbox(app.SelectionPanel);
            app.KruskalWallisCheckBox.ValueChangedFcn = createCallbackFcn(app, @KruskalWallisCheckBoxValueChanged, true);
            app.KruskalWallisCheckBox.Text = 'Kruskal-Wallis';
            app.ReductionPanel = uipanel(app.FeatureSelectionReductionPanel);
            app.ReductionPanel.Title = 'Reduction';
            app.ModelDropDown_3Label = uilabel(app.ReductionPanel);
            app.ModelDropDown_3Label.Text = 'Model';
            app.ModelDropDown_3 = uidropdown(app.ReductionPanel);
            app.ModelDropDown_3.Items = {'None', 'PCA', 'LDA', ''};
            app.ModelDropDown_3.Value = 'None';
            app.NFeaturesEditFieldLabel = uilabel(app.FeatureSelectionReductionPanel);
            app.NFeaturesEditFieldLabel.Text = 'N Features';
            app.NFeaturesEditField = uieditfield(app.FeatureSelectionReductionPanel, 'numeric');
            app.NFeaturesEditField.Value = 1;
            app.PlotButton = uibutton(app.FeatureSelectionReductionPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClassificationPanel = uipanel(app.UIFigure);
            app.ClassificationPanel.Title = '6 - Classification';
            app.ClassifyButton = uibutton(app.ClassificationPanel, 'push');
            app.ClassifyButton.Text = 'Classify';
            app.ModelDropDownLabel = uilabel(app.ClassificationPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.ClassificationPanel);
            app.ModelDropDown.Items = {'Euclidean Linear Discriminant', 'Mahalanobis Linear Discriminant', 'Fisher Linear Discriminant', 'K - Nearest Neighbors', 'Support Vector Machine', ''};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Euclidean Linear Discriminant';
            app.CostEditField_2Label = uilabel(app.ClassificationPanel);
            app.CostEditField_2Label.Visible = 'off';
            app.CostEditField_2Label.Text = 'Cost';
            app.CostEditField_2 = uieditfield(app.ClassificationPanel, 'numeric');
            app.CostEditField_2.Visible = 'off';
            app.GammaEditField_2Label = uilabel(app.ClassificationPanel);
            app.GammaEditField_2Label.Visible = 'off';
            app.GammaEditField_2Label.Text = 'Gamma';
            app.GammaEditField_2 = uieditfield(app.ClassificationPanel, 'numeric');
            app.GammaEditField_2.Visible = 'off';
            app.NeighborsEditField_2Label = uilabel(app.ClassificationPanel);
            app.NeighborsEditField_2Label.Visible = 'off';
            app.NeighborsEditField_2Label.Text = 'Neighbors';
            app.NeighborsEditField_2 = uieditfield(app.ClassificationPanel, 'numeric');
            app.NeighborsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NeighborsEditField_2ValueChanged, true);
            app.NeighborsEditField_2.Visible = 'off';
            app.ScenariosPanel = uipanel(app.UIFigure);
            app.ScenariosPanel.Title = '1 - Scenarios';
            app.SelectDropDownLabel = uilabel(app.ScenariosPanel);
            app.SelectDropDownLabel.Text = 'Select';
            app.SelectDropDown = uidropdown(app.ScenariosPanel);
            app.SelectDropDown.Items = {'Scenario A', 'Scenario B', 'Scenario C', ''};
            app.SelectDropDown.ItemsData = {'1', '2', '3', ''};
            app.SelectDropDown.Value = '1';
            app.FeatureAssessmentPanel = uipanel(app.UIFigure);
            app.FeatureAssessmentPanel.Title = '3 - Feature Assessment';
            app.AssessButton = uibutton(app.FeatureAssessmentPanel, 'push');
            app.AssessButton.Text = 'Assess';
            app.AssessButton_2 = uibutton(app.FeatureAssessmentPanel, 'push');
            app.AssessButton_2.ButtonPushedFcn = createCallbackFcn(app, @AssessButton_2Pushed, true);
            app.AssessButton_2.Text = 'Assess';
            app.PreprocessingPanel = uipanel(app.UIFigure);
            app.PreprocessingPanel.Title = '2 - Pre-processing';
            app.ScaleCheckBox = uicheckbox(app.PreprocessingPanel);
            app.ScaleCheckBox.Text = 'Scale';
            app.HyperparametersTunningPanel = uipanel(app.UIFigure);
            app.HyperparametersTunningPanel.Title = '5 - Hyper-parameters Tunning';
            app.SearchButton = uibutton(app.HyperparametersTunningPanel, 'push');
            app.SearchButton.Text = 'Search';
            app.ModelDropDown_2Label = uilabel(app.HyperparametersTunningPanel);
            app.ModelDropDown_2Label.Text = 'Model';
            app.ModelDropDown_2 = uidropdown(app.HyperparametersTunningPanel);
            app.ModelDropDown_2.Items = {'K - Nearest Neighbors', 'Support Vector Machine'};
            app.ModelDropDown_2.ItemsData = {'K - Nearest Neighbors', 'Support Vector Machine'};
            app.ModelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModelDropDown_2ValueChanged, true);
            app.ModelDropDown_2.Value = 'K - Nearest Neighbors';
            app.NeighborsEditFieldLabel = uilabel(app.HyperparametersTunningPanel);
            app.NeighborsEditFieldLabel.Text = 'Neighbors';
            app.NeighborsEditField = uieditfield(app.HyperparametersTunningPanel, 'numeric');
            app.CostEditFieldLabel = uilabel(app.HyperparametersTunningPanel);
            app.CostEditFieldLabel.Visible = 'off';
            app.CostEditFieldLabel.Text = 'Cost';
            app.CostEditField = uieditfield(app.HyperparametersTunningPanel, 'numeric');
            app.CostEditField.Visible = 'off';
            app.GammaEditFieldLabel = uilabel(app.HyperparametersTunningPanel);
            app.GammaEditFieldLabel.Visible = 'off';
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.HyperparametersTunningPanel, 'numeric');
            app.GammaEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end